classdef collaborativeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RadiosondeAnalysisApp          matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        AnalysisaltituderangeMenu      matlab.ui.container.Menu
        SavedirectoryMenu              matlab.ui.container.Menu
        AboutthisappMenu               matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        gwaveTab                       matlab.ui.container.Tab
        powerSpectrumPlot              matlab.ui.control.UIAxes
        TropopauseEditFieldLabel       matlab.ui.control.Label
        TropopauseEditField            matlab.ui.control.NumericEditField
        PBLEditFieldLabel              matlab.ui.control.Label
        PBLEditField                   matlab.ui.control.NumericEditField
        WindshearEditFieldLabel        matlab.ui.control.Label
        WindshearEditField             matlab.ui.control.EditField
        LowleveljetsEditFieldLabel     matlab.ui.control.Label
        LowleveljetsEditField          matlab.ui.control.EditField
        SavedataButton                 matlab.ui.control.Button
        gravityWavePlot                matlab.ui.control.UIAxes
        JetstreamEditFieldLabel        matlab.ui.control.Label
        JetstreamEditField             matlab.ui.control.NumericEditField
        HodoshearEditFieldLabel        matlab.ui.control.Label
        HodoshearEditField             matlab.ui.control.EditField
        tropoCheckBox                  matlab.ui.control.CheckBox
        pblCheckBox                    matlab.ui.control.CheckBox
        windShearCheckBox              matlab.ui.control.CheckBox
        lljCheckBox                    matlab.ui.control.CheckBox
        critLayerCheckBox              matlab.ui.control.CheckBox
        jetStreamCheckBox              matlab.ui.control.CheckBox
        bulkShearCheckBox              matlab.ui.control.CheckBox
        capeCheckBox                   matlab.ui.control.CheckBox
        liCheckBox                     matlab.ui.control.CheckBox
        hodoShearCheckBox              matlab.ui.control.CheckBox
        ClearselectedvaluesButton      matlab.ui.control.Button
        CAPEEditFieldLabel             matlab.ui.control.Label
        CAPEEditField                  matlab.ui.control.NumericEditField
        LIEditFieldLabel               matlab.ui.control.Label
        LIEditField                    matlab.ui.control.NumericEditField
        CriticallayerEditFieldLabel    matlab.ui.control.Label
        CriticallayerEditField         matlab.ui.control.NumericEditField
        BulkshearEditFieldLabel        matlab.ui.control.Label
        BulkshearEditField             matlab.ui.control.EditField
        AlloftheabovelevelsareinkmLabel  matlab.ui.control.Label
        pblTab                         matlab.ui.container.Tab
        pbl_pt_plot                    matlab.ui.control.UIAxes
        pbl_ri_plot                    matlab.ui.control.UIAxes
        pbl_sh_plot                    matlab.ui.control.UIAxes
        pbl_temp_plot                  matlab.ui.control.UIAxes
        pbl_CheckBox_pt                matlab.ui.control.CheckBox
        pbl_CheckBox_ri                matlab.ui.control.CheckBox
        pbl_CheckBox_sh                matlab.ui.control.CheckBox
        PBLHeightsLabel                matlab.ui.control.Label
        RiLabel                        matlab.ui.control.Label
        PtLabel                        matlab.ui.control.Label
        HuLabel                        matlab.ui.control.Label
        TypeLabel                      matlab.ui.control.Label
        windshearTab                   matlab.ui.container.Tab
        windShearPlot                  matlab.ui.control.UIAxes
        waveandshearTab                matlab.ui.container.Tab
        gravityWaveDataTable           matlab.ui.control.Table
        currentFlightLabel             matlab.ui.control.Label
        singleGravityWaveSoundingPlot  matlab.ui.control.UIAxes
        windSpeedPlot                  matlab.ui.control.UIAxes
        multipleprofilesTab            matlab.ui.container.Tab
        SelectfilesButton              matlab.ui.control.Button
        wavesOverTime                  matlab.ui.control.UIAxes
        LoadfileButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentFile % File currently being analyzed.
        lowerCutOffAltitude = 0;
        upperCutOffAltitude = 40;
        saveDirectory = 'gravityWaveData';
        tropopause = [];
        lowLevelJets = [];
        windShearLevels = [];
        planetaryBoundaryLayer = [];
        latitudeArray = [];
        altitude = [];
        longitudeArray = [];
        data = []; 
        fileList = [];
        hodoShearLevels = [];
        criticalLayer = [];
        jetStream = [];
        cape = [];
        liftedIndex = [];
        bulkShearLevels = [];
        waveletFileList = [];
        waveletTransforms = [];
        alts = [];
        shearLevels = [];
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           addpath('wave_matlab/');
           warning('off', 'MATLAB:polyfit:RepeatedPointsOrRescale');
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            if isequal(file, 0)
                % disp('User selected Cancel');
                return 
            end
            cla(app.powerSpectrumPlot);
            cla(app.gravityWavePlot);
            %Update PBL Tab View
            %Requires pbl_calculation.m and readSondeFile.m (not included with .mlapp file)
            [PBLpt, PBLri, PBLsh, PBL_Type] = pbl_calculation(fullfile(path,file),app.pbl_CheckBox_pt.Value,...
                app.pbl_CheckBox_ri.Value, app.pbl_CheckBox_sh.Value, ...
                app.pbl_pt_plot, app.pbl_ri_plot, app.pbl_sh_plot, ...
                app.pbl_temp_plot);
            app.RiLabel.Text = sprintf('Ri: %.2f m',PBLri);
            app.PtLabel.Text = sprintf('Pt: %.2f m',PBLpt);
            app.HuLabel.Text = sprintf('Hu: %.2f m',PBLsh);
            app.TypeLabel.Text = sprintf('%s',PBL_Type);
            
            app.currentFile = fullfile(path, file);
            showPowerSurfaces = false;
            save = false;
            [~, titleName, ~] = fileparts(app.currentFile);
            set(app.powerSpectrumPlot, 'YScale', 'log')
            titleString = sprintf("%s", titleName);
            title(app.powerSpectrumPlot, titleString, 'Interpreter', 'none');
            [app.latitudeArray, app.longitudeArray, app.altitude, ...
                app.data, waveletTransform, clippedAlt, ...
                gWaveLocations, windSpeed] = doAnalysis(app.currentFile, save, app.saveDirectory, showPowerSurfaces, app.lowerCutOffAltitude*1000, app.upperCutOffAltitude*1000, 30, 50, false);
            if isempty(app.data)
                title(app.gravityWavePlot, 'No gravity waves detected');
                msgbox(sprintf("No gravity waves detected for %s", titleName));
            end
            plotGravityWaveData(app.data, app.altitude, waveletTransform, gWaveLocations, clippedAlt, app.powerSpectrumPlot, app.gravityWavePlot);  
            cla(app.windShearPlot);
            app.shearLevels = shearRedo(app.currentFile, app.windShearPlot);
            
            
            cla(app.singleGravityWaveSoundingPlot);
            cla(app.windSpeedPlot);
            save = false;
            showPowerSurfaces = false;
            cur = fullfile(path, file);
            [~, ~, altitude, ...
                data, ~, ~, ...
                ~, windSpeed] = doAnalysis(cur, save, app.saveDirectory, showPowerSurfaces, app.lowerCutOffAltitude*1000, app.upperCutOffAltitude*1000, 30, 50, false);
            if isempty(data)
                title(app.gravityWavePlot, 'No gravity waves detected');
                msgbox(sprintf("No gravity waves detected for %s", file));
                return
            end
            app.currentFlightLabel.Text = file;
            plotGravityWaveData(data, altitude, [], [], [], app.powerSpectrumPlot, app.singleGravityWaveSoundingPlot);
            app.gravityWaveDataTable.ColumnName = data.Properties.VariableNames;
            data.propagation_dir = azimuthFromUnitCircle(data.propagation_dir);
            app.gravityWaveDataTable.Data = data;
            shearLevels = shearRedo(cur, app.windSpeedPlot);


        end

        % Value changed function: TropopauseEditField
        function TropopauseEditFieldValueChanged(app, event)
        try    
            app.tropopause = app.TropopauseEditField.Value;
            plotSignificantLevels(app.tropopause, app.gravityWavePlot, 'tropopause', 'g');
            legend(app.gravityWavePlot);

        catch
            helpdlg("Tropopause edit field only accepts altitude of tropopause in km.")
        end
        end

        % Value changed function: PBLEditField
        function PBLEditFieldValueChanged(app, event)
        try     
            app.planetaryBoundaryLayer = app.PBLEditField.Value;
            plotSignificantLevels(app.planetaryBoundaryLayer, app.gravityWavePlot, 'pbl', 'm');
            legend(app.gravityWavePlot);
        catch
            helpdlg("PBL edit field only accepts altitude of tropopause in km.")
        end
            
        end

        % Value changed function: WindshearEditField
        function WindshearEditFieldValueChanged(app, event)
           try    
            value = app.WindshearEditField.Value;
            s = split(value, ',');
            app.windShearLevels = cellfun(@str2num,s(1:end));
            plotSignificantLevels(app.windShearLevels, app.gravityWavePlot, 'wind shear', 'y');
            legend(app.gravityWavePlot);
        catch
           helpdlg("Wind shear edit field only accepts a comma-delimited list of wind shear levels in km.")
           end
        end

        % Value changed function: LowleveljetsEditField
        function LowleveljetsEditFieldValueChanged(app, event)
            try
            value = app.LowleveljetsEditField.Value;
            s = split(value, ',');
            app.lowLevelJets = cellfun(@str2num,s(1:end));
            plotSignificantLevels(app.lowLevelJets, app.gravityWavePlot, 'low level jets', 'b');
            catch
                helpdlg("Low level jets edit field only accepts a comma-delimited list of jet levels in km.")
            end
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            if isempty(app.tropopause)
                app.tropopause = NaN;
            end
            if isempty(app.planetaryBoundaryLayer)
                app.planetaryBoundaryLayer = NaN;
            end
            if isempty(app.windShearLevels)
               app.windShearLevels = NaN;
            end
            if isempty(app.lowLevelJets)
               app.lowLevelJets = NaN;
            end
            maxLength = max(size(app.windShearLevels), size(app.lowLevelJets));
            maxLength = max(maxLength, size(app.bulkShearLevels));
            maxLength = maxLength(1, 1);
            
            if maxLength == 0
                maxLength = 1;
            end
            if ~isfolder(app.saveDirectory)
                status = mkdir(app.saveDirectory);
                if status == 0
                    helpdlg(sprintf("Could not make directory %s\n. Choose a new directory name.", app.saveDirectory));
                end
            end
            if isempty(app.currentFile)
                return;
            end
            
            llj = zeros(1, maxLength);
            capesave = zeros(1, maxLength);
            capesave(1) = app.cape;
            jetlayersave = zeros(1, maxLength);
            jetlayersave(1) = app.jetStream;
            critLayer = zeros(1, maxLength);
            critLayer(1) = app.criticalLayer;
            liftindex = zeros(1, maxLength);
            liftindex(1) = app.liftedIndex;
            llj(1:size(app.lowLevelJets)) = app.lowLevelJets*1000;
            wsl = zeros(1, maxLength);
            wsl(1:size(app.windShearLevels)) = app.windShearLevels*1000;
            tropo = zeros(1, maxLength);
            tropo(1) = app.tropopause*1000;
            bulkshear = zeros(1, maxLength);
            bulkshear(1:size(app.bulkShearLevels)) = app.bulkShearLevels*1000;
            pbl = zeros(1, maxLength);
            pbl(1) = app.planetaryBoundaryLayer*1000;
            header = {'wind_shear_levels', 'low_level_jets', 'tropopause', ...
                'planetary_boundary_layer', 'bulk_shear', 'cape', 'lifted_index', 'critical_layer', 'jet_stream'};
            tbl = [wsl; llj; tropo; pbl; bulkshear; capesave; liftindex; critLayer; jetlayersave]';
            dataBlock = array2table(tbl, 'VariableNames', header);
            [~, saveFileName, ~] = fileparts(app.currentFile);
            saveFileNameSignificantLevels = strcat(saveFileName, '_significant_levels.csv');
            saveFileNameGravityWaveData = strcat(saveFileName, '_gravity_wave_parameters.csv');
            saveFileNameSignificantLevels = fullfile(app.saveDirectory, saveFileNameSignificantLevels);
            saveFileNameGravityWaveData = fullfile(app.saveDirectory, saveFileNameGravityWaveData);
            
            if isfile(saveFileNameSignificantLevels)
                s = sprintf("File %s already exists. Would you like to overwrite the data? If no, levels are appended to the file.", saveFileNameSignificantLevels);
                answer = questdlg(s, 'Overwrite or append', 'Yes', 'No', 'Yes');
                switch answer
                    case 'Yes'
                       writetable(dataBlock, saveFileNameSignificantLevels);
                    case 'No'
                        dlmwrite(saveFileNameSignificantLevels, tbl, 'delimiter', ',', '-append');
                end
            else
                writetable(dataBlock, saveFileNameSignificantLevels);
            end
            writetable(app.data, saveFileNameGravityWaveData);
        end

        % Menu selected function: AnalysisaltituderangeMenu
        function AnalysisaltituderangeMenuSelected(app, event)
           x = inputdlg({'Lower altitude bound (km)', 'Upper altitude bound (km). A value of 40 goes to the end of the sounding.'}, 'Altitude range selection', [1 25; 1, 25], {num2str(app.lowerCutOffAltitude), num2str(app.upperCutOffAltitude)});
           if isempty(x)
               return;
           end
           app.lowerCutOffAltitude = str2double(x{1})*1000;
           app.upperCutOffAltitude = str2double(x{2})*1000;
        end

        % Menu selected function: SavedirectoryMenu
        function SavedirectoryMenuSelected(app, event)
           prompt = {'Enter a directory to save data in'};
           dlgtitle = 'Directory';
           definput = {'gravityWaveData'};
           opts.Interpreter = 'tex';
           app.saveDirectory = inputdlg(prompt,dlgtitle,[1 40],definput,opts);
           if isempty(app.saveDirectory)
               return
           end
           app.saveDirectory = app.saveDirectory{1};
        end

        % Menu selected function: AboutthisappMenu
        function AboutthisappMenuSelected(app, event)
            msgstring = fileread('readmeGUI.txt');
            msgbox(msgstring, 'About this app','help');
        end

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            [app.fileList, path] = uigetfile('*.txt', "MultiSelect", 'on');
            if isempty(app.fileList)
                return;
            end
            cla(app.wavesOverTime)
            hold(app.wavesOverTime, 'on');
            [app.waveletFileList, app.waveletTransforms] = plotTimeSeriesProfiles(app.fileList, path, app.lowerCutOffAltitude, ...
                app.upperCutOffAltitude, app.wavesOverTime);
        end

        % Button pushed function: ClearselectedvaluesButton
        function ClearselectedvaluesButtonPushed(app, event)
            
           % switch case. Redraw at end.
           cla(app.gravityWavePlot);
           plotGravityWaveData(app.data, app.altitude, [], [], [], app.powerSpectrumPlot, app.gravityWavePlot);
           if app.bulkShearCheckBox.Value == 0
                plotSignificantLevels(app.bulkShearLevels, app.gravityWavePlot, 'bulk shear', 'r');
           else
                app.BulkshearEditField.Value = '0';
           end 
           if app.tropoCheckBox.Value == 0
              plotSignificantLevels(app.tropopause, app.gravityWavePlot, 'tropopause', 'g');
           else   
              app.TropopauseEditField.Value = 0;
           end  
           if app.lljCheckBox.Value == 0
                plotSignificantLevels(app.lowLevelJets, app.gravityWavePlot, 'low level jets', 'b');
           else
               app.LowleveljetsEditField.Value = '0';
           end 
           if app.pblCheckBox.Value == 0
               plotSignificantLevels(app.planetaryBoundaryLayer, app.gravityWavePlot, 'pbl', 'm');
           else
               app.PBLEditField.Value = 0;
           end
           if app.critLayerCheckBox.Value == 0
               plotSignificantLevels(app.criticalLayer, app.gravityWavePlot, 'critical layer', 'g');
           else
               app.CriticallayerEditField.Value = 0;
           end
           if app.windShearCheckBox.Value == 0
               plotSignificantLevels(app.windShearLevels, app.gravityWavePlot, 'wind shear', 'y');
           else
               app.WindshearEditField.Value = '0';
           end
           if app.jetStreamCheckBox.Value == 0
               plotSignificantLevels(app.jetStream, app.gravityWavePlot, 'jet stream', 'k');
           else
               app.JetstreamEditField.Value = 0;
           end
           if app.capeCheckBox.Value
               app.CAPEEditField.Value = 0;
               app.cape = 0;
           end
           if app.hodoShearCheckBox.Value == 0
               plotSignificantLevels(app.hodoShearLevels, app.gravityWavePlot, 'hodo shear', 'k');
           else
               app.HodoshearEditField.Value = '0';
           end
           if app.liCheckBox.Value
               app.liftedIndex = 0;
               app.LIEditField.Value = 0;
           end
           legend(app.gravityWavePlot);
        end

        % Value changed function: JetstreamEditField
        function JetstreamEditFieldValueChanged(app, event)
            try     
                app.jetStream = app.JetstreamEditField.Value;
                plotSignificantLevels(app.jetStream, app.gravityWavePlot, 'jet stream', 'k');
                legend(app.gravityWavePlot)
        catch
                helpdlg("Jet streams edit field only accepts altitude in km.")
            end
            
            
        end

        % Value changed function: HodoshearEditField
        function HodoshearEditFieldValueChanged(app, event)
            try
            value = app.HodoshearEditField.Value;
            s = split(value, ',');
            app.hodoShearLevels = cellfun(@str2num,s(1:end));
            plotSignificantLevels(app.hodoShearLevels, app.gravityWavePlot, 'hodo shear', 'k');
            catch
                helpdlg("Hodograph shear edit field only accepts a comma-delimited list of levels in km.")
            end
            
        end

        % Callback function
        function CriticallayerEditFieldValueChanged(app, event)
            try     
            app.criticalLayer = app.CriticallayerEditField.Value;
            plotSignificantLevels(app.criticalLayer, app.gravityWavePlot, 'critical layer', 'g');
            legend(app.gravityWavePlot)
        catch
            helpdlg("Critical layer edit field only accepts altitude in km.")
            end
            
        end

        % Value changed function: BulkshearEditField
        function BulkshearEditFieldValueChanged2(app, event)
            try
            value = app.BulkshearEditField.Value;
            s = split(value, ',');
            app.bulkShearLevels = cellfun(@str2num,s(1:end));
            plotSignificantLevels(app.bulkShearLevels, app.gravityWavePlot, 'bulk shear', 'r');
            legend(app.gravityWavePlot)
            catch
                helpdlg("Bulk shear edit field only accepts a comma-delimited list of levels in km.")
            end
        end

        % Value changed function: CriticallayerEditField
        function CriticallayerEditFieldValueChanged2(app, event)
        try     
            app.criticalLayer = app.CriticallayerEditField.Value;
            plotSignificantLevels(app.criticalLayer, app.gravityWavePlot, 'critical layer', 'g');
            legend(app.gravityWavePlot)
        catch
            helpdlg("Critical layer edit field only accepts altitude in km.")
        end
            
        end

        % Callback function
        function SelectsoundingButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            if isequal(file, 0)
                % disp('User selected Cancel');
                return 
            end
            cla(app.singleGravityWaveSoundingPlot);
            cla(app.windSpeedPlot);
            save = false;
            showPowerSurfaces = false;
            cur = fullfile(path, file);
            [~, ~, altitude, ...
                data, ~, ~, ...
                ~, windSpeed] = doAnalysis(cur, save, app.saveDirectory, showPowerSurfaces, app.lowerCutOffAltitude*1000, app.upperCutOffAltitude*1000);
            if isempty(data)
                title(app.gravityWavePlot, 'No gravity waves detected');
                msgbox(sprintf("No gravity waves detected for %s", file));
                return
            end
            app.currentFlightLabel.Text = file;
            plotGravityWaveData(data, altitude, [], [], [], app.powerSpectrumPlot, app.singleGravityWaveSoundingPlot);
            app.gravityWaveDataTable.ColumnName = data.Properties.VariableNames;
            data.propagation_dir = azimuthFromUnitCircle(data.propagation_dir);
            app.gravityWaveDataTable.Data = data;
            shearLevels = shearRedo(cur, app.windSpeedPlot);
            %plot(app.windSpeedPlot, windSpeed, altitude/1000);
            %hold(app.windSpeedPlot, 'on');
            %plotSignificantLevels(app.shearLevels / 1000, app.windSpeedPlot, 'shear', 'k')
        end

        % Value changed function: CAPEEditField
        function CAPEEditFieldValueChanged(app, event)
            app.cape = app.CAPEEditField.Value;
            
        end

        % Value changed function: LIEditField
        function LIEditFieldValueChanged(app, event)
            app.liftedIndex = app.LIEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RadiosondeAnalysisApp = uifigure('Visible', 'off');
            app.RadiosondeAnalysisApp.Name = 'Radiosonde Analysis App';
            app.Menu = uimenu(app.RadiosondeAnalysisApp);
            app.Menu.Text = 'Menu';
            app.AnalysisaltituderangeMenu = uimenu(app.Menu);
            app.AnalysisaltituderangeMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisaltituderangeMenuSelected, true);
            app.AnalysisaltituderangeMenu.Text = 'Analysis altitude range';
            app.SavedirectoryMenu = uimenu(app.Menu);
            app.SavedirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedirectoryMenuSelected, true);
            app.SavedirectoryMenu.Text = 'Save directory';
            app.AboutthisappMenu = uimenu(app.Menu);
            app.AboutthisappMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutthisappMenuSelected, true);
            app.AboutthisappMenu.Text = 'About this app';
            app.TabGroup = uitabgroup(app.RadiosondeAnalysisApp);
            app.gwaveTab = uitab(app.TabGroup);
            app.gwaveTab.Title = 'g-wave';
            app.powerSpectrumPlot = uiaxes(app.gwaveTab);
            title(app.powerSpectrumPlot, '')
            xlabel(app.powerSpectrumPlot, '')
            ylabel(app.powerSpectrumPlot, '')
            app.TropopauseEditFieldLabel = uilabel(app.gwaveTab);
            app.TropopauseEditFieldLabel.Text = 'Tropopause';
            app.TropopauseEditField = uieditfield(app.gwaveTab, 'numeric');
            app.TropopauseEditField.ValueChangedFcn = createCallbackFcn(app, @TropopauseEditFieldValueChanged, true);
            app.TropopauseEditField.Tooltip = {'Enter tropopause height in km.'};
            app.PBLEditFieldLabel = uilabel(app.gwaveTab);
            app.PBLEditFieldLabel.Text = 'PBL';
            app.PBLEditField = uieditfield(app.gwaveTab, 'numeric');
            app.PBLEditField.ValueChangedFcn = createCallbackFcn(app, @PBLEditFieldValueChanged, true);
            app.PBLEditField.Tooltip = {'PBL height in km'};
            app.WindshearEditFieldLabel = uilabel(app.gwaveTab);
            app.WindshearEditFieldLabel.Text = 'Wind shear';
            app.WindshearEditField = uieditfield(app.gwaveTab, 'text');
            app.WindshearEditField.ValueChangedFcn = createCallbackFcn(app, @WindshearEditFieldValueChanged, true);
            app.WindshearEditField.Tooltip = {'Wind shear (km). Enter multiple values as comma-delimited list'};
            app.LowleveljetsEditFieldLabel = uilabel(app.gwaveTab);
            app.LowleveljetsEditFieldLabel.Text = 'Low level jets';
            app.LowleveljetsEditField = uieditfield(app.gwaveTab, 'text');
            app.LowleveljetsEditField.ValueChangedFcn = createCallbackFcn(app, @LowleveljetsEditFieldValueChanged, true);
            app.LowleveljetsEditField.Tooltip = {'Enter multiple values as a comma delimited list'};
            app.SavedataButton = uibutton(app.gwaveTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.gravityWavePlot = uiaxes(app.gwaveTab);
            title(app.gravityWavePlot, '')
            xlabel(app.gravityWavePlot, '')
            ylabel(app.gravityWavePlot, '')
            app.gravityWavePlot.XTick = [];
            app.gravityWavePlot.XTickLabel = '';
            app.JetstreamEditFieldLabel = uilabel(app.gwaveTab);
            app.JetstreamEditFieldLabel.Text = 'Jet stream';
            app.JetstreamEditField = uieditfield(app.gwaveTab, 'numeric');
            app.JetstreamEditField.ValueChangedFcn = createCallbackFcn(app, @JetstreamEditFieldValueChanged, true);
            app.JetstreamEditField.Tooltip = {'Enter tropopause height in km.'};
            app.HodoshearEditFieldLabel = uilabel(app.gwaveTab);
            app.HodoshearEditFieldLabel.Text = 'Hodo shear';
            app.HodoshearEditField = uieditfield(app.gwaveTab, 'text');
            app.HodoshearEditField.ValueChangedFcn = createCallbackFcn(app, @HodoshearEditFieldValueChanged, true);
            app.HodoshearEditField.Tooltip = {'Enter multiple values as a comma delimited list'};
            app.tropoCheckBox = uicheckbox(app.gwaveTab);
            app.tropoCheckBox.Text = '';
            app.pblCheckBox = uicheckbox(app.gwaveTab);
            app.pblCheckBox.Text = '';
            app.windShearCheckBox = uicheckbox(app.gwaveTab);
            app.windShearCheckBox.Text = '';
            app.lljCheckBox = uicheckbox(app.gwaveTab);
            app.lljCheckBox.Text = '';
            app.critLayerCheckBox = uicheckbox(app.gwaveTab);
            app.critLayerCheckBox.Text = '';
            app.jetStreamCheckBox = uicheckbox(app.gwaveTab);
            app.jetStreamCheckBox.Text = '';
            app.bulkShearCheckBox = uicheckbox(app.gwaveTab);
            app.bulkShearCheckBox.Text = '';
            app.capeCheckBox = uicheckbox(app.gwaveTab);
            app.capeCheckBox.Text = '';
            app.liCheckBox = uicheckbox(app.gwaveTab);
            app.liCheckBox.Text = '';
            app.hodoShearCheckBox = uicheckbox(app.gwaveTab);
            app.hodoShearCheckBox.Text = '';
            app.ClearselectedvaluesButton = uibutton(app.gwaveTab, 'push');
            app.ClearselectedvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectedvaluesButtonPushed, true);
            app.ClearselectedvaluesButton.Text = 'Clear selected values';
            app.CAPEEditFieldLabel = uilabel(app.gwaveTab);
            app.CAPEEditFieldLabel.Text = 'CAPE';
            app.CAPEEditField = uieditfield(app.gwaveTab, 'numeric');
            app.CAPEEditField.ValueChangedFcn = createCallbackFcn(app, @CAPEEditFieldValueChanged, true);
            app.CAPEEditField.Tooltip = {'PBL height in km'};
            app.LIEditFieldLabel = uilabel(app.gwaveTab);
            app.LIEditFieldLabel.Text = 'LI';
            app.LIEditField = uieditfield(app.gwaveTab, 'numeric');
            app.LIEditField.ValueChangedFcn = createCallbackFcn(app, @LIEditFieldValueChanged, true);
            app.LIEditField.Tooltip = {'PBL height in km'};
            app.CriticallayerEditFieldLabel = uilabel(app.gwaveTab);
            app.CriticallayerEditFieldLabel.Text = 'Critical layer';
            app.CriticallayerEditField = uieditfield(app.gwaveTab, 'numeric');
            app.CriticallayerEditField.ValueChangedFcn = createCallbackFcn(app, @CriticallayerEditFieldValueChanged2, true);
            app.CriticallayerEditField.Tooltip = {'PBL height in km'};
            app.BulkshearEditFieldLabel = uilabel(app.gwaveTab);
            app.BulkshearEditFieldLabel.Text = 'Bulk shear';
            app.BulkshearEditField = uieditfield(app.gwaveTab, 'text');
            app.BulkshearEditField.ValueChangedFcn = createCallbackFcn(app, @BulkshearEditFieldValueChanged2, true);
            app.BulkshearEditField.Tooltip = {'Wind shear (km). Enter multiple values as comma-delimited list'};
            app.AlloftheabovelevelsareinkmLabel = uilabel(app.gwaveTab);
            app.AlloftheabovelevelsareinkmLabel.Text = 'All of the above levels are in km.';
            app.pblTab = uitab(app.TabGroup);
            app.pblTab.Title = 'pbl';
            app.pbl_pt_plot = uiaxes(app.pblTab);
            title(app.pbl_pt_plot, 'Title')
            xlabel(app.pbl_pt_plot, 'X')
            ylabel(app.pbl_pt_plot, 'Y')
            app.pbl_ri_plot = uiaxes(app.pblTab);
            title(app.pbl_ri_plot, 'Title')
            xlabel(app.pbl_ri_plot, 'X')
            ylabel(app.pbl_ri_plot, 'Y')
            app.pbl_sh_plot = uiaxes(app.pblTab);
            title(app.pbl_sh_plot, 'Title')
            xlabel(app.pbl_sh_plot, 'X')
            ylabel(app.pbl_sh_plot, 'Y')
            app.pbl_temp_plot = uiaxes(app.pblTab);
            title(app.pbl_temp_plot, 'Title')
            xlabel(app.pbl_temp_plot, 'X')
            ylabel(app.pbl_temp_plot, 'Y')
            app.pbl_CheckBox_pt = uicheckbox(app.pblTab);
            app.pbl_CheckBox_pt.Text = 'Pt Gradient Plot';
            app.pbl_CheckBox_pt.Value = true;
            app.pbl_CheckBox_ri = uicheckbox(app.pblTab);
            app.pbl_CheckBox_ri.Text = 'Richardson Plot';
            app.pbl_CheckBox_ri.Value = true;
            app.pbl_CheckBox_sh = uicheckbox(app.pblTab);
            app.pbl_CheckBox_sh.Text = 'sHu Gradient Plot';
            app.pbl_CheckBox_sh.Value = true;
            app.PBLHeightsLabel = uilabel(app.pblTab);
            app.PBLHeightsLabel.Text = 'PBL Heights:';
            app.RiLabel = uilabel(app.pblTab);
            app.RiLabel.Text = 'Ri:';
            app.PtLabel = uilabel(app.pblTab);
            app.PtLabel.Text = 'Pt: ';
            app.HuLabel = uilabel(app.pblTab);
            app.HuLabel.Text = 'Hu: ';
            app.TypeLabel = uilabel(app.pblTab);
            app.TypeLabel.Text = 'Type:';
            app.windshearTab = uitab(app.TabGroup);
            app.windshearTab.Title = 'wind shear';
            app.windShearPlot = uiaxes(app.windshearTab);
            title(app.windShearPlot, 'Wind shear levels')
            xlabel(app.windShearPlot, 'X')
            ylabel(app.windShearPlot, 'Y')
            app.waveandshearTab = uitab(app.TabGroup);
            app.waveandshearTab.Title = 'wave and shear';
            app.gravityWaveDataTable = uitable(app.waveandshearTab);
            app.gravityWaveDataTable.ColumnName = {'Alt. of detection'; 'w/f'; 'prop. dir'; 'horiz. phase vel'};
            app.gravityWaveDataTable.RowName = {};
            app.currentFlightLabel = uilabel(app.waveandshearTab);
            app.currentFlightLabel.Text = 'Flight name';
            app.singleGravityWaveSoundingPlot = uiaxes(app.waveandshearTab);
            title(app.singleGravityWaveSoundingPlot, 'Title')
            xlabel(app.singleGravityWaveSoundingPlot, '')
            ylabel(app.singleGravityWaveSoundingPlot, 'altitude')
            app.windSpeedPlot = uiaxes(app.waveandshearTab);
            title(app.windSpeedPlot, 'Wind speed')
            xlabel(app.windSpeedPlot, 'wind speed (m/s')
            ylabel(app.windSpeedPlot, '')
            app.multipleprofilesTab = uitab(app.TabGroup);
            app.multipleprofilesTab.Title = 'multiple-profiles';
            app.SelectfilesButton = uibutton(app.multipleprofilesTab, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select files';
            app.wavesOverTime = uiaxes(app.multipleprofilesTab);
            title(app.wavesOverTime, 'Title')
            xlabel(app.wavesOverTime, 'X')
            ylabel(app.wavesOverTime, 'Y')
            app.LoadfileButton = uibutton(app.RadiosondeAnalysisApp, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            % Show the figure after all components are created
            app.RadiosondeAnalysisApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = collaborativeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RadiosondeAnalysisApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RadiosondeAnalysisApp)
        end
    end
end