classdef gravity_wave_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        PreviousButton                 matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        NextButton                     matlab.ui.control.Button
        FitellipseButton               matlab.ui.control.Button
        NextdatafileButton             matlab.ui.control.Button
        SavefitButton                  matlab.ui.control.Button
        OpenfilebrowserButton          matlab.ui.control.Button
        DoneTextAreaLabel              matlab.ui.control.Label
        DoneTextArea                   matlab.ui.control.TextArea
        InvertwindowedtransformButton  matlab.ui.control.Button
        OnequartermaxwindowButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        data_files
        file_counter = 1;
        selpath
        rows
        cols
        local_maxima_counter = 0;
        waveletTransform;
        windowedWaveletTransform;
        userDrawnRectangle = false;
        current_max_row
        current_max_col
    end
    
    methods (Access = public)
        % TODO: Add a callback on both axes that handles the user drawing a
        % rectangle. The button ``invert wavelet transform'' should get
        % the bounding box around the identified local maxima.
        function display_text(app, string)
            app.DoneTextArea.Value = string;
        end
        
        function display_windowed_power_surface(app, color)
           ofs = 99;
           if(app.local_maxima_counter > size(app.rows, 1))
               display_text(app, "Out of local maxima. Move to next file.")
               app.local_maxima_counter = 1;
               % TODO: Better handling of this case.
           end
           if (app.local_maxima_counter < 1)
               app.local_maxima_counter = 1;
           end
           n_rows = size(app.waveletTransform.power_surface, 1);
           n_cols = size(app.waveletTransform.power_surface, 2);
           app.current_max_row = app.rows(app.local_maxima_counter);
           app.current_max_col = app.cols(app.local_maxima_counter);
           first_row = app.current_max_row - ofs;
           last_row = app.current_max_row + ofs;
           first_col = app.current_max_col - ofs;
           last_col = app.current_max_col + ofs;
           if (app.current_max_row-ofs) < 1
               first_row = 1;
           end
           if (app.current_max_row + ofs) > n_rows
               last_row = n_rows;
           end
           if (app.current_max_col + ofs) > n_cols
               last_col = n_cols;
           end
           if (app.current_max_col - ofs) < 1
               first_col = 1;
           end
           %hold(app.UIAxes, 'off');          
           app.windowedWaveletTransform = WindowedWaveletTransform(first_row, last_row, first_col, last_col);
           contourf(app.UIAxes_2, app.waveletTransform.power_surface(first_row:last_row, first_col:last_col));
           hold(app.UIAxes_2, 'on');
           % display location of max on the second axes.
           % TODO: Map arrow keys to next/previous.
           center_row = 99;
           center_col = 99;
           if first_row == 1
               center_row = app.current_max_row; 
           end
           if first_col == 1
               center_col = app.current_max_col;
           end
           
           
           scatter(app.UIAxes_2, center_col+1, center_row+1, [150], 'rx');
           hold(app.UIAxes_2, 'off');
           hold(app.UIAxes, 'on')
           scatter(app.UIAxes, app.current_max_col, app.current_max_row,  color);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenfilebrowserButton
        function OpenfilebrowserButtonPushed(app, event)
            app.file_counter = 1;
            app.selpath = uigetdir();
            if app.selpath == 0
                return
            end
            txtpattern = fullfile(app.selpath, '*.txt');
            app.data_files = dir(txtpattern);

        end

        % Button pushed function: NextdatafileButton
        function NextdatafileButtonPushed(app, event)
            if isempty(app.data_files)
               return
            end
            if size(app.data_files) < app.file_counter
                app.file_counter = 1;
            end
            current_file = app.data_files(app.file_counter);
            current_file = current_file.name;
            file = fullfile(app.selpath, current_file);
            display_text(app, "Loading data...");
            data = importfile(file);
            cla(app.UIAxes, "reset");
            cla(app.UIAxes_2, "reset");
            display_text(app, int2str(app.file_counter));
            app.file_counter = app.file_counter + 1;
            if isempty(data)
                display_text(app, "Empty data file.");
                return
            end
            mai = maximum_altitude_index(data.Alt);
            if mai < 1000
                display_text(app, "Sonde flight did not reach required altitude.");
                display_text(current_file);
                return
            end
            ws = data.Ws(1:mai);
            wd = data.Wd(1:mai);
            u_wind_component = ws.*cosd(wd);
            v_wind_component = ws.*sind(wd);
            time = data.Ti(1:mai);
            dt = time(2) - time(1);
            app.waveletTransform = WaveletTransform(u_wind_component, v_wind_component, dt);
            display_text(app, "Done calculating transform");
            title(app.UIAxes, current_file);
            contourf(app.UIAxes, app.waveletTransform.power_surface);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.waveletTransform.coi, 'k')
            temp = app.waveletTransform.power_surface;
            temp(temp < 0.1) = 0; % arbitrary noise thresholding.
            points = imregionalmax(temp, 8);
            [app.rows, app.cols] = find(points);
            scatter(app.UIAxes,[app.cols], [app.rows], [], 'ro');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.local_maxima_counter = app.local_maxima_counter + 1;   
            display_windowed_power_surface(app, 'gx');
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
           app.local_maxima_counter = app.local_maxima_counter - 1;
           display_windowed_power_surface(app, 'kx');
        end

        % Button pushed function: InvertwindowedtransformButton
        function InvertwindowedtransformButtonPushed(app, event)
            if isempty(app.windowedWaveletTransform)
                display_text(app, "no windowed transform selected");
                return;
            end
            if isempty(app.waveletTransform)
                return;
            end
            [u_inverted, v_inverted] = app.waveletTransform.invertWindowedTransform(app.windowedWaveletTransform);
            hold(app.UIAxes_2, 'off');
            plot(app.UIAxes_2, u_inverted, v_inverted);
        end

        % Button pushed function: OnequartermaxwindowButton
        function OnequartermaxwindowButtonPushed(app, event)
            if (app.userDrawnRectangle)
                % Get max
                five = 5;
            else
                [row_index_1, row_index_2, col_index_1, col_index_2] = app.waveletTransform.clipWindowedTransformToValue(app.windowedWaveletTransform, app.current_max_row, app.current_max_col);
            end
            size(app.waveletTransform.power_surface)
            app.windowedWaveletTransform = WindowedWaveletTransform(row_index_1, row_index_2, col_index_1, col_index_2);
            contourf(app.UIAxes_2, app.waveletTransform.power_surface(row_index_1:row_index_2, col_index_1:col_index_2));
            %hold(app.UIAxes_2, 'on');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wavelet Power Surface')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FitellipseButton = uibutton(app.UIFigure, 'push');
            app.FitellipseButton.Text = 'Fit ellipse';
            app.NextdatafileButton = uibutton(app.UIFigure, 'push');
            app.NextdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @NextdatafileButtonPushed, true);
            app.NextdatafileButton.Text = 'Next data file';
            app.SavefitButton = uibutton(app.UIFigure, 'push');
            app.SavefitButton.Text = 'Save fit';
            app.OpenfilebrowserButton = uibutton(app.UIFigure, 'push');
            app.OpenfilebrowserButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfilebrowserButtonPushed, true);
            app.OpenfilebrowserButton.Text = 'Open file browser';
            app.DoneTextAreaLabel = uilabel(app.UIFigure);
            app.DoneTextAreaLabel.Text = 'Done';
            app.DoneTextArea = uitextarea(app.UIFigure);
            app.InvertwindowedtransformButton = uibutton(app.UIFigure, 'push');
            app.InvertwindowedtransformButton.ButtonPushedFcn = createCallbackFcn(app, @InvertwindowedtransformButtonPushed, true);
            app.InvertwindowedtransformButton.Text = 'Invert windowed transform';
            app.OnequartermaxwindowButton = uibutton(app.UIFigure, 'push');
            app.OnequartermaxwindowButton.ButtonPushedFcn = createCallbackFcn(app, @OnequartermaxwindowButtonPushed, true);
            app.OnequartermaxwindowButton.Text = 'One-quarter max window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gravity_wave_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end